classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        UIAxes              matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        Tab2                matlab.ui.container.Tab
        Panel               matlab.ui.container.Panel
        Knob_Angle          matlab.ui.control.DiscreteKnob
        cLabel              matlab.ui.control.Label
        EF_Angle            matlab.ui.control.NumericEditField
        Label               matlab.ui.control.Label
        EF_Angle_2          matlab.ui.control.NumericEditField
        EditField_2Label    matlab.ui.control.Label
        EF_DAngle           matlab.ui.control.NumericEditField
        Panel_2             matlab.ui.container.Panel
        cLabel_2            matlab.ui.control.Label
        EF_Angle_3          matlab.ui.control.NumericEditField
        EditField_2Label_2  matlab.ui.control.Label
        EF_DAngle_2         matlab.ui.control.NumericEditField
        Knob_Angle_2        matlab.ui.control.DiscreteKnob
        PB_GetData          matlab.ui.control.Button
        Label_3             matlab.ui.control.Label
        EF_Angle_4          matlab.ui.control.NumericEditField
        Panel_3             matlab.ui.container.Panel
        Label_4             matlab.ui.control.Label
        Button              matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        Button_3            matlab.ui.control.Button
        CheckBox            matlab.ui.control.CheckBox
        CheckBox_2          matlab.ui.control.CheckBox
        CheckBox_3          matlab.ui.control.CheckBox
        Label_5             matlab.ui.control.Label
        CheckBox_4          matlab.ui.control.CheckBox
        Button_4            matlab.ui.control.Button
        ButtonGroup         matlab.ui.container.ButtonGroup
        QBFocButton         matlab.ui.control.RadioButton
        dfmFocButton        matlab.ui.control.RadioButton
        ParamPanel          matlab.ui.container.TabGroup
        QBTab               matlab.ui.container.Tab
        QEditFieldLabel     matlab.ui.control.Label
        QEditField          matlab.ui.control.NumericEditField
        BEditFieldLabel     matlab.ui.control.Label
        BEditField          matlab.ui.control.NumericEditField
        E12EditFieldLabel   matlab.ui.control.Label
        E12EditField        matlab.ui.control.NumericEditField
        E22EditFieldLabel   matlab.ui.control.Label
        E22EditField        matlab.ui.control.NumericEditField
        dfmTab              matlab.ui.container.Tab
        systype             matlab.ui.container.ButtonGroup
        Kass                matlab.ui.control.RadioButton
        Greg                matlab.ui.control.RadioButton
        dEditFieldLabel     matlab.ui.control.Label
        dEditField          matlab.ui.control.NumericEditField
        fmEditFieldLabel    matlab.ui.control.Label
        fmEditField         matlab.ui.control.NumericEditField
        Panel_4             matlab.ui.container.Panel
        DEditFieldLabel     matlab.ui.control.Label
        DEditField          matlab.ui.control.NumericEditField
        CEditFieldLabel     matlab.ui.control.Label
        CEditField          matlab.ui.control.NumericEditField
        FOCEditFieldLabel   matlab.ui.control.Label
        FOCEditField        matlab.ui.control.NumericEditField
        FreqEditFieldLabel  matlab.ui.control.Label
        FreqEditField       matlab.ui.control.NumericEditField
        Label_6             matlab.ui.control.Label
        SWzoomin            matlab.ui.control.Switch
        Label_7             matlab.ui.control.Label
        SWpan               matlab.ui.control.Switch
        Button_5            matlab.ui.control.Button
    end


    properties (Access = private)
        V_allParam; % Description
        SysVal;
        AxesLimits;
        
        FlagQBdfm=1;%1-QB,2-dfm
        FlagKassGreg=1;%1-Kass,2-Greg
        FlagZoom=0;%0-off,1-in,2-out
        FlagPan=0;%0-off,1-on
        Flag_res=0;% наличие расчетов
        Flag_Paraminit=1;%-инициализация параметров
        Flag_foc=1;
        Flag_mass=[1 1 1]; % Отображение линий
        Flag_tip=4;%1-сагитт,2-мерид,3 полн,4-на передачу
        Flag_error=0%расчет фронта падения
        
        V_AxesH;
        V_Mirrow;
        V_Angle;
        V_Angle2;
        
        V_RESULT;
        V_FAZE; % Description
        V_Diag; % Description
    end

    methods (Access = private)
    
        function  Fzoomoff(app)
            for i=1:numel(app.V_AxesH)
                zoom(app.V_AxesH{i},'off');
            end
            app.SWzoomin.Value='Off';
        end
        function results = F_Allparam(app)
            try
            app.Flag_res=0;
            app.V_allParam=[];
            
            FOC=app.FOCEditField.Value;%
            Diam=app.DEditField.Value;%
            Clearence=app.CEditField.Value;%
            Freq=app.FreqEditField.Value;%
            lambda=app.SysVal.Csveta/Freq;%
            if app.FlagQBdfm==1
                Q=app.QEditField.Value;%
                B=app.BEditField.Value;%
                V_t=sign(B);
                Q2=abs(Q)*V_t;%
                E12=1+2*B.^2.*(Q2./(1-Q2));%
                E22=((1+B)./(1-B)).^2+2*B.^2./(1-Q2)./((1-B).^3);%
                
                app.E12EditField.Value=E12;
                app.E22EditField.Value=E22;
               
                R1=V_t*2*B*FOC;%
                R2=V_t*(2*B*Q2*FOC./(1-B));%
                
                struct.B=B;
                struct.Q2=Q2;
                struct.E12=E12;
                struct.E22=E22;

                struct.d=V_t*B*(1-Q2)*FOC;
                struct.delta=-V_t*FOC*(Q2-B*(1-Q2));
                
                app.dEditField.Value=struct.d;
                app.fmEditField.Value=struct.delta;
            else
                d=app.dEditField.Value;
                delta=app.fmEditField.Value;
                
                ro0=d-delta;

                
                if app.FlagKassGreg==1
                    dfmsgn=1;

                else
                    dfmsgn=-1;
                end
                Q=dfmsgn*ro0./FOC;
                B=dfmsgn*d./(1-Q)./FOC;
                R1=dfmsgn*2*B*FOC;%
                R2=dfmsgn*(2*B*Q*FOC./(1-B));%
                
                app.QEditField.Value=Q;
                app.BEditField.Value=B;
                Q2=Q;
                E12=1+2*B.^2.*(Q2./(1-Q2));%
                E22=((1+B)./(1-B)).^2+2*B.^2./(1-Q2)./((1-B).^3);%
                app.E12EditField.Value=E12;
                app.E22EditField.Value=E22;
                
                struct.dfmsgn=dfmsgn;
                struct.B=B;
                struct.Q2=Q2;
                struct.d=d;
                struct.delta=delta;
                struct.E12=E12;
                struct.E22=E22;
            end
            
            struct.R1=R1;
            struct.R2=R2;
            struct.FOC=FOC;
            struct.lambda=lambda;
            struct.Freq=Freq;
            struct.D=Diam;
            struct.C=Clearence;
            
            results=struct;
            
            app.Flag_Paraminit=1;

            catch
            app.Flag_Paraminit=0;
            end
        end
        
        function F_DataINIT(app)
            
            set(app.QEditField,'Value',abs(app.V_allParam.Q2));
            set(app.BEditField,'Value',app.V_allParam.B);
            set(app.E12EditField,'Value',app.V_allParam.E12);
            set(app.E22EditField,'Value',app.V_allParam.E22);
            set(app.dEditField,'Value',app.V_allParam.d);
            set(app.fmEditField,'Value',app.V_allParam.delta);
            
            set(app.FOCEditField,'Value',app.V_allParam.FOC);
            set(app.CEditField,'Value',app.V_allParam.C);
            set(app.DEditField,'Value',app.V_allParam.D);
        end
        
        function results = F_MirrowINIT(app)
            if app.Flag_Paraminit
                if app.V_allParam.D>0
                    color='red';Width=2;
                    cla(app.UIAxes);
                    app.V_Mirrow=F_MirrowDataAllPlanar(app.V_allParam,app.SysVal,app.FlagQBdfm);
                    
                    h=plot(app.UIAxes,app.V_Mirrow(1).Z,app.V_Mirrow(1).X);
                    h.Color=color;h.LineWidth=Width;
                    hold(app.UIAxes,'on');
                    h=plot(app.UIAxes,app.V_Mirrow(2).Z,app.V_Mirrow(2).X);
                    h.Color=color;h.LineWidth=Width;
                    %xlim(app.UIAxes);
                    %
                    %настоящий фокус
                    if app.Flag_foc(1)
                        h=plot(app.UIAxes,app.V_allParam.delta,0);
                        h.Color='blue';h.Marker='diamond';h.MarkerSize=10;h.MarkerFaceColor='blue';
                    end
                    if ~app.Flag_res
                        FOC=app.V_allParam.FOC*2;
                        Tzapas=1.15;

                        %-------------сагиттальная плоскость
                        set(app.UIAxes,'XLim',app.V_Mirrow(1).ZLIM*Tzapas);
                        set(app.UIAxes,'YLim',app.V_Mirrow(1).XLIM*Tzapas);
                        %-------------меридиональная плоскость

                        %------------сечения
                        app.AxesLimits(1).XLim=app.V_Mirrow(1).ZLIM*Tzapas;
                        app.AxesLimits(1).YLim=app.V_Mirrow(1).XLIM*Tzapas;
                    end
                end
            else
            end
        end
        
        function results = Geomplot(app)
            F_MirrowINIT(app);
            if app.Flag_res
            else
            end
        end
        function AngleInit(app)
            A=round(app.EF_Angle.Value,1);
            B=round(app.EF_Angle_2.Value,1);
            DA=round(app.EF_DAngle.Value,1);
            Items=-B:DA:A;
            for i=1:numel(Items)
                ITEMS{i}=num2str(Items(i));
            end
            set(app.Knob_Angle,'Items',ITEMS);
            app.V_Angle=Items;
        end
        
        function AngleInit2(app)
            A=round(app.EF_Angle_3.Value,1);
            B=round(app.EF_Angle_4.Value,1);
            DA=round(app.EF_DAngle_2.Value,1);
            Items=-B:DA:A;
            for i=1:numel(Items)
                ITEMS{i}=num2str(Items(i));
            end
            set(app.Knob_Angle_2,'Items',ITEMS);
            app.V_Angle2=Items;
        end
        function flagtipreset(app)
            app.Button.BackgroundColor=[0.96,0.96,0.96];
            app.Button_2.BackgroundColor=[0.96,0.96,0.96];
            app.Button_3.BackgroundColor=[0.96,0.96,0.96];
            app.Button_4.BackgroundColor=[0.96,0.96,0.96];
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SysVal.Csveta=299792458; % metr per sec
            app.SysVal.MirTol=100;
            app.V_AxesH={app.UIAxes,app.UIAxes_2};
            app.CEditField.Value=app.DEditField.Value/2;
            app.V_allParam = F_Allparam(app);
            Geomplot(app);
            %------------
            AngleInit(app);
            AngleInit2(app);
            Button_3Pushed(app,app);
        end

        % Value changed function: SWpan
        function SWpanValueChanged(app, event)
            value = app.SWpan.Value;
            if strcmp(value,'On')
                for i=1:numel(app.V_AxesH)
                    pan(app.V_AxesH{i},'on');
                end
                Fzoomoff(app);
            else
                for i=1:numel(app.V_AxesH)
                    pan(app.V_AxesH{i},'off');
                end

            end
        end

        % Value changed function: SWzoomin
        function SWzoominValueChanged(app, event)
            value = app.SWzoomin.Value;
            if strcmp(value,'On')
                for i=1:numel(app.V_AxesH)
                    pan(app.V_AxesH{i},'off');
                end
                for i=1:numel(app.V_AxesH)
                    zoom(app.V_AxesH{i},'on');
                end
                app.SWpan.Value='Off';
            else
                Fzoomoff(app);
            end
        end

        % Callback function
        function SWzoomoutValueChanged(app, event)

        end

        % Selection changed function: systype
        function systypeSelectionChanged(app, event)
            selectedButton = app.systype.SelectedObject;
            if strcmp(selectedButton.Text,'Предфокальные')
                app.FlagKassGreg=1;%1-Kass,2-Greg
            elseif strcmp(selectedButton.Text,'Зафокальные')
                app.FlagKassGreg=2;%1-Kass,2-Greg
            end
            app.V_allParam = F_Allparam(app);
            F_DataINIT(app);
            Geomplot(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Q, B, Foc')
                app.FlagQBdfm=1;%1-QB
                app.QEditField.Editable='on';
                app.BEditField.Editable='on';
                app.dEditField.Editable='off';
                app.fmEditField.Editable='off';
                
                if app.V_allParam.B>0
                    app.systype.SelectedObject=app.Kass;
                    app.FlagKassGreg=1;
                    app.Greg.Enable='off';
                else
                    app.systype.SelectedObject=app.Greg;
                    app.FlagKassGreg=2;
                    app.Kass.Enable='off';
                end
            elseif strcmp(selectedButton.Text,'d, fm, Foc')
                app.Kass.Enable='on';
                app.Greg.Enable='on';
                
                app.FlagQBdfm=2;%2-d fm
                app.QEditField.Editable='off';
                app.BEditField.Editable='off';
                app.dEditField.Editable='on';
                app.fmEditField.Editable='on';
                
                if app.V_allParam.B>0
                    app.systype.SelectedObject=app.Kass;
                    app.FlagKassGreg=1;
                else
                    app.systype.SelectedObject=app.Greg;
                    app.FlagKassGreg=2;
                end
            end
            app.V_allParam = F_Allparam(app);
            F_DataINIT(app);
            Geomplot(app);
        end

        % Value changed function: QEditField
        function QEditFieldValueChanged(app, event)
            app.V_allParam = F_Allparam(app);
            F_DataINIT(app);
            Geomplot(app);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            Fzoomoff(app);
            set(app.UIAxes,'XLim',app.AxesLimits(1).XLim);
            set(app.UIAxes,'YLim',app.AxesLimits(1).YLim);

        end

        % Value changed function: EF_Angle_3
        function EF_Angle_3ValueChanged(app, event)
            value = app.EF_Angle_3.Value;
            AngleInit2(app);
        end

        % Value changed function: EF_Angle_4
        function EF_Angle_4ValueChanged(app, event)
            value = app.EF_Angle_4.Value;
            AngleInit2(app);
        end

        % Value changed function: EF_DAngle_2
        function EF_DAngle_2ValueChanged(app, event)
            value = app.EF_DAngle_2.Value;
            AngleInit2(app);
        end

        % Value changed function: EF_Angle
        function EF_AngleValueChanged(app, event)
            value = app.EF_Angle.Value;
            AngleInit(app);
        end

        % Value changed function: EF_Angle_2
        function EF_Angle_2ValueChanged(app, event)
            value = app.EF_Angle_2.Value;
            AngleInit(app);
        end

        % Value changed function: EF_DAngle
        function EF_DAngleValueChanged(app, event)
            value = app.EF_DAngle.Value;
            AngleInit(app);
        end

        % Value changed function: Knob_Angle_2
        function Knob_Angle_2ValueChanged(app, event)
            value = app.Knob_Angle_2.Value;
            if app.Flag_res
                Geomplot(app);
            end
        end

        % Value changed function: Knob_Angle
        function Knob_AngleValueChanged(app, event)
            value = app.Knob_Angle.Value;
            AngleInit(app);
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            value = app.CheckBox_4.Value;
            app.Flag_foc=value;
            Geomplot(app);
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            app.Flag_mass(1)=value;
            Geomplot(app);
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            app.Flag_mass(2)=value;
            Geomplot(app);
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            app.Flag_mass(3)=value;
            Geomplot(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Flag_tip=1;
            Geomplot(app);
            flagtipreset(app);
            app.Button.BackgroundColor=[0.47,0.67,0.19];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Flag_tip=2;
            Geomplot(app);
            flagtipreset(app);
            app.Button_2.BackgroundColor=[0.47,0.67,0.19];
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.Flag_tip=3;
            Geomplot(app);
            flagtipreset(app);
            app.Button_4.BackgroundColor=[0.47,0.67,0.19];
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Flag_tip=4;
            Geomplot(app);
            flagtipreset(app);
            app.Button_3.BackgroundColor=[0.47,0.67,0.19];
        end

        % Value changed function: FOCEditField
        function FOCEditFieldValueChanged(app, event)
            app.V_allParam = F_Allparam(app);
            F_DataINIT(app);
            Geomplot(app);
        end

        % Value changed function: DEditField
        function DEditFieldValueChanged(app, event)
            app.V_allParam = F_Allparam(app);
            F_DataINIT(app);
            Geomplot(app);
        end

        % Value changed function: CEditField
        function CEditFieldValueChanged(app, event)
            app.V_allParam = F_Allparam(app);
            F_DataINIT(app);
            Geomplot(app);
        end

        % Value changed function: FreqEditField
        function FreqEditFieldValueChanged(app, event)
            value = app.FreqEditField.Value;
            
        end

        % Value changed function: BEditField
        function BEditFieldValueChanged(app, event)
            app.V_allParam = F_Allparam(app);
            F_DataINIT(app);
            Geomplot(app);
        end

        % Value changed function: dEditField
        function dEditFieldValueChanged(app, event)
            app.V_allParam = F_Allparam(app);
            F_DataINIT(app);
            Geomplot(app);
        end

        % Value changed function: fmEditField
        function fmEditFieldValueChanged(app, event)
            app.V_allParam = F_Allparam(app);
            F_DataINIT(app);
            Geomplot(app);
        end

        % Button pushed function: PB_GetData
        function PB_GetDataButtonPushed(app, event)
            [app.V_RESULT,app.Flag_error]=GetDataReflection(app.V_Mirrow,app.V_Angle2,app.V_allParam);
            N=str2num(app.Knob_Angle2.Value);
            ind=find(app.V_Angle==N);
            signB=sign(app.V_allParam.B);
            app.Flag_res=1;
            Geomplot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Плоская геометрия';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Геометрия')
            xlabel(app.UIAxes, 'Z')
            ylabel(app.UIAxes, 'X')
            app.UIAxes_2 = uiaxes(app.Tab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Отклонение в горизонтальной плоскости';
            app.Knob_Angle = uiknob(app.Panel, 'discrete');
            app.Knob_Angle.Items = {'-4', '-3', '-2', '-1', '0', '1', '2', '3', '4'};
            app.Knob_Angle.ValueChangedFcn = createCallbackFcn(app, @Knob_AngleValueChanged, true);
            app.Knob_Angle.Value = '0';
            app.cLabel = uilabel(app.Panel);
            app.cLabel.Text = {'Макc. '; 'отклонение'};
            app.EF_Angle = uieditfield(app.Panel, 'numeric');
            app.EF_Angle.ValueChangedFcn = createCallbackFcn(app, @EF_AngleValueChanged, true);
            app.EF_Angle.Value = 4;
            app.Label = uilabel(app.Panel);
            app.Label.Text = {'Мин. '; 'отклонение'};
            app.EF_Angle_2 = uieditfield(app.Panel, 'numeric');
            app.EF_Angle_2.ValueChangedFcn = createCallbackFcn(app, @EF_Angle_2ValueChanged, true);
            app.EF_Angle_2.Value = 4;
            app.EditField_2Label = uilabel(app.Panel);
            app.EditField_2Label.Text = 'Шаг';
            app.EF_DAngle = uieditfield(app.Panel, 'numeric');
            app.EF_DAngle.ValueChangedFcn = createCallbackFcn(app, @EF_DAngleValueChanged, true);
            app.EF_DAngle.Value = 1;
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = 'Отклонение в вертикальной плоскости';
            app.cLabel_2 = uilabel(app.Panel_2);
            app.cLabel_2.Text = {'Макc. '; 'отклонение'};
            app.EF_Angle_3 = uieditfield(app.Panel_2, 'numeric');
            app.EF_Angle_3.ValueChangedFcn = createCallbackFcn(app, @EF_Angle_3ValueChanged, true);
            app.EF_Angle_3.Value = 4;
            app.EditField_2Label_2 = uilabel(app.Panel_2);
            app.EditField_2Label_2.Text = 'Шаг';
            app.EF_DAngle_2 = uieditfield(app.Panel_2, 'numeric');
            app.EF_DAngle_2.ValueChangedFcn = createCallbackFcn(app, @EF_DAngle_2ValueChanged, true);
            app.EF_DAngle_2.Value = 1;
            app.Knob_Angle_2 = uiknob(app.Panel_2, 'discrete');
            app.Knob_Angle_2.Items = {'-4', '-3', '-2', '-1', '0', '1', '2', '3', '4'};
            app.Knob_Angle_2.ValueChangedFcn = createCallbackFcn(app, @Knob_Angle_2ValueChanged, true);
            app.Knob_Angle_2.Value = '0';
            app.PB_GetData = uibutton(app.Panel_2, 'push');
            app.PB_GetData.ButtonPushedFcn = createCallbackFcn(app, @PB_GetDataButtonPushed, true);
            app.PB_GetData.Text = 'Рассчитать';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = {'Мин. '; 'отклонение'};
            app.EF_Angle_4 = uieditfield(app.Panel_2, 'numeric');
            app.EF_Angle_4.ValueChangedFcn = createCallbackFcn(app, @EF_Angle_4ValueChanged, true);
            app.EF_Angle_4.Value = 4;
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = 'Настройки отображения';
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = 'Вид';
            app.Button = uibutton(app.Panel_3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Сагиттальный';
            app.Button_2 = uibutton(app.Panel_3, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Меридиональный';
            app.Button_3 = uibutton(app.Panel_3, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = 'На передачу';
            app.CheckBox = uicheckbox(app.Panel_3);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = 'цвет 1';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.Panel_3);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = 'цвет 2';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.Panel_3);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = 'цвет 3';
            app.CheckBox_3.Value = true;
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = 'Трассировка';
            app.CheckBox_4 = uicheckbox(app.Panel_3);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = {'Основной '; 'фокус'};
            app.CheckBox_4.Value = true;
            app.Button_4 = uibutton(app.Panel_3, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = 'Полный';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Выбор параметров расчета';
            app.QBFocButton = uiradiobutton(app.ButtonGroup);
            app.QBFocButton.Text = 'Q, B, Foc';
            app.QBFocButton.Value = true;
            app.dfmFocButton = uiradiobutton(app.ButtonGroup);
            app.dfmFocButton.Text = 'd, fm, Foc';
            app.ParamPanel = uitabgroup(app.UIFigure);
            app.QBTab = uitab(app.ParamPanel);
            app.QBTab.Title = 'QB';
            app.QEditFieldLabel = uilabel(app.QBTab);
            app.QEditFieldLabel.Text = 'Q';
            app.QEditField = uieditfield(app.QBTab, 'numeric');
            app.QEditField.ValueChangedFcn = createCallbackFcn(app, @QEditFieldValueChanged, true);
            app.QEditField.Value = 0.3;
            app.BEditFieldLabel = uilabel(app.QBTab);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.QBTab, 'numeric');
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @BEditFieldValueChanged, true);
            app.BEditField.Value = -0.6;
            app.E12EditFieldLabel = uilabel(app.QBTab);
            app.E12EditFieldLabel.Text = 'E1^2';
            app.E12EditField = uieditfield(app.QBTab, 'numeric');
            app.E12EditField.Editable = 'off';
            app.E22EditFieldLabel = uilabel(app.QBTab);
            app.E22EditFieldLabel.Text = 'E2^2';
            app.E22EditField = uieditfield(app.QBTab, 'numeric');
            app.E22EditField.Editable = 'off';
            app.dfmTab = uitab(app.ParamPanel);
            app.dfmTab.Title = 'd, fm';
            app.systype = uibuttongroup(app.dfmTab);
            app.systype.SelectionChangedFcn = createCallbackFcn(app, @systypeSelectionChanged, true);
            app.systype.Title = 'Тип системы';
            app.Kass = uiradiobutton(app.systype);
            app.Kass.Text = 'Предфокальные';
            app.Kass.Value = true;
            app.Greg = uiradiobutton(app.systype);
            app.Greg.Text = 'Зафокальные';
            app.dEditFieldLabel = uilabel(app.dfmTab);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.dfmTab, 'numeric');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged, true);
            app.dEditField.Editable = 'off';
            app.dEditField.Value = 1;
            app.fmEditFieldLabel = uilabel(app.dfmTab);
            app.fmEditFieldLabel.Text = 'fm';
            app.fmEditField = uieditfield(app.dfmTab, 'numeric');
            app.fmEditField.ValueChangedFcn = createCallbackFcn(app, @fmEditFieldValueChanged, true);
            app.fmEditField.Editable = 'off';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = 'Panel';
            app.DEditFieldLabel = uilabel(app.Panel_4);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.Panel_4, 'numeric');
            app.DEditField.ValueChangedFcn = createCallbackFcn(app, @DEditFieldValueChanged, true);
            app.DEditField.Value = 1;
            app.CEditFieldLabel = uilabel(app.Panel_4);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.Panel_4, 'numeric');
            app.CEditField.ValueChangedFcn = createCallbackFcn(app, @CEditFieldValueChanged, true);
            app.FOCEditFieldLabel = uilabel(app.Panel_4);
            app.FOCEditFieldLabel.Text = 'FOC';
            app.FOCEditField = uieditfield(app.Panel_4, 'numeric');
            app.FOCEditField.ValueChangedFcn = createCallbackFcn(app, @FOCEditFieldValueChanged, true);
            app.FOCEditField.Value = 1;
            app.FreqEditFieldLabel = uilabel(app.Panel_4);
            app.FreqEditFieldLabel.Text = 'Freq';
            app.FreqEditField = uieditfield(app.Panel_4, 'numeric');
            app.FreqEditField.ValueChangedFcn = createCallbackFcn(app, @FreqEditFieldValueChanged, true);
            app.FreqEditField.Value = 1000000000;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Увеличение';
            app.SWzoomin = uiswitch(app.UIFigure, 'slider');
            app.SWzoomin.ValueChangedFcn = createCallbackFcn(app, @SWzoominValueChanged, true);
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'Смещение';
            app.SWpan = uiswitch(app.UIFigure, 'slider');
            app.SWpan.ValueChangedFcn = createCallbackFcn(app, @SWpanValueChanged, true);
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = 'Сброс';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end